classdef maze_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MazeGeneratorGUIUIFigure  matlab.ui.Figure
        MazepropertiesPanel       matlab.ui.container.Panel
        SaveaspngButton           matlab.ui.control.Button
        HeightSlider              matlab.ui.control.Slider
        HeightSliderLabel         matlab.ui.control.Label
        WidthSlider               matlab.ui.control.Slider
        WidthSliderLabel          matlab.ui.control.Label
        GenerateButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        width = 1; 
        height = 1; 
        maze
        plot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WidthSlider
        function WidthSliderValueChanged(app, event)
            app.width = app.WidthSlider.Value;
%             assignin("base","sloupce_raw",app.width);
        end

        % Value changed function: HeightSlider
        function HeightSliderValueChanged(app, event)
            app.height = app.HeightSlider.Value;
%             assignin("base","radky_raw",app.height);
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            sloupce = round(app.width);
            radky = round(app.height);
%             assignin("base","radky",radky);
%             assignin("base","sloupce",sloupce);
            app.maze = generatemaze(radky,sloupce);
            map = binaryOccupancyMap(app.maze);
            app.plot = show(map);
            axis off;
            title('Maze');
            app.SaveaspngButton.Enable = 'on';
        end

        % Button pushed function: SaveaspngButton
        function SaveaspngButtonPushed(app, event)
            savestring = strcat('maze',datestr(now, 'yyyy-mm-dd_HH-MM-SS'),'.png');
            saveas(app.plot,savestring)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MazeGeneratorGUIUIFigure = uifigure('Visible', 'off');
            app.MazeGeneratorGUIUIFigure.Name = 'Maze Generator GUI';
            app.MazepropertiesPanel = uipanel(app.MazeGeneratorGUIUIFigure);
            app.MazepropertiesPanel.Title = 'Maze properties';
            app.GenerateButton = uibutton(app.MazepropertiesPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.WidthSliderLabel = uilabel(app.MazepropertiesPanel);
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.MazepropertiesPanel);
            app.WidthSlider.Limits = [1 100];
            app.WidthSlider.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.WidthSlider.ValueChangedFcn = createCallbackFcn(app, @WidthSliderValueChanged, true);
            app.WidthSlider.MinorTicks = [];
            app.WidthSlider.Value = 1;
            app.HeightSliderLabel = uilabel(app.MazepropertiesPanel);
            app.HeightSliderLabel.Text = 'Height';
            app.HeightSlider = uislider(app.MazepropertiesPanel);
            app.HeightSlider.Limits = [1 100];
            app.HeightSlider.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.HeightSlider.ValueChangedFcn = createCallbackFcn(app, @HeightSliderValueChanged, true);
            app.HeightSlider.MinorTicks = [];
            app.HeightSlider.Value = 1;
            app.SaveaspngButton = uibutton(app.MazepropertiesPanel, 'push');
            app.SaveaspngButton.ButtonPushedFcn = createCallbackFcn(app, @SaveaspngButtonPushed, true);
            app.SaveaspngButton.Enable = 'off';
            app.SaveaspngButton.Text = 'Save as *.png';
            % Show the figure after all components are created
            app.MazeGeneratorGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maze_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MazeGeneratorGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MazeGeneratorGUIUIFigure)
        end
    end
end